classdef Resistivity_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        DevicesPanel                matlab.ui.container.Panel
        Keithley2400LampLabel       matlab.ui.control.Label
        Keithley2400Lamp            matlab.ui.control.Lamp
        Keithley6221LampLabel       matlab.ui.control.Label
        Keithley6221Lamp            matlab.ui.control.Lamp
        LockinLampLabel             matlab.ui.control.Label
        LockinLamp                  matlab.ui.control.Lamp
        PowerSwitchLabel            matlab.ui.control.Label
        PowerSwitch                 matlab.ui.control.ToggleSwitch
        k6621AddressEditField       matlab.ui.control.NumericEditField
        AddressLabel                matlab.ui.control.Label
        k2400AddressEditField       matlab.ui.control.NumericEditField
        LockinAddressEditField      matlab.ui.control.NumericEditField
        ArduinoLampLabel            matlab.ui.control.Label
        ArduinoLamp                 matlab.ui.control.Lamp
        ArduinoAddressEditField     matlab.ui.control.EditField
        AcquisitionPanel            matlab.ui.container.Panel
        SelectDirectoryButton       matlab.ui.control.Button
        FileTagEditFieldLabel       matlab.ui.control.Label
        FileTagEditField            matlab.ui.control.EditField
        FrequencyHzEditFieldLabel   matlab.ui.control.Label
        FrequencyHzEditField        matlab.ui.control.NumericEditField
        AmplitudeAEditFieldLabel    matlab.ui.control.Label
        AmplitudeAEditField         matlab.ui.control.NumericEditField
        StartButton                 matlab.ui.control.Button
        AbortButton                 matlab.ui.control.Button
        DirectoryTextAreaLabel      matlab.ui.control.Label
        DirectoryTextArea           matlab.ui.control.TextArea
        MeasurementTypeTabGroup     matlab.ui.container.TabGroup
        GatedTab                    matlab.ui.container.Tab
        VgendVEditFieldLabel        matlab.ui.control.Label
        VgendVEditField             matlab.ui.control.NumericEditField
        VgstartVEditFieldLabel      matlab.ui.control.Label
        VgstartVEditField           matlab.ui.control.NumericEditField
        VgstepVEditFieldLabel       matlab.ui.control.Label
        VgstepVEditField            matlab.ui.control.NumericEditField
        ReverseSwitchLabel          matlab.ui.control.Label
        ReverseSwitch               matlab.ui.control.Switch
        CyclesEditFieldLabel        matlab.ui.control.Label
        CyclesEditField             matlab.ui.control.NumericEditField
        UngatedTab                  matlab.ui.container.Tab
        TimesEditFieldLabel         matlab.ui.control.Label
        TimesEditField              matlab.ui.control.NumericEditField
        TimestepsEditFieldLabel     matlab.ui.control.Label
        TimestepsEditField          matlab.ui.control.NumericEditField
        AveragesEditFieldLabel      matlab.ui.control.Label
        AveragesEditField           matlab.ui.control.NumericEditField
        GateSwitchLabel             matlab.ui.control.Label
        GateSwitch                  matlab.ui.control.Switch
        LoadFile                    matlab.ui.control.Button
        GraphsPanel                 matlab.ui.container.Panel
        RVAxes                      matlab.ui.control.UIAxes
        IdleLampLabel               matlab.ui.control.Label
        StatusLamp                  matlab.ui.control.Lamp
        DatapointLabel              matlab.ui.control.Label
        DatapointEditField          matlab.ui.control.EditField
        VgVEditFieldLabel           matlab.ui.control.Label
        VgVEditField                matlab.ui.control.NumericEditField
        ROhmsEditFieldLabel         matlab.ui.control.Label
        ROhmsEditField              matlab.ui.control.NumericEditField
        SweeprateVsEditFieldLabel   matlab.ui.control.Label
        SweeprateVsEditField        matlab.ui.control.NumericEditField
        ElapsedtimesEditFieldLabel  matlab.ui.control.Label
        ElapsedtimesEditField       matlab.ui.control.NumericEditField
        IleakageAEditFieldLabel     matlab.ui.control.Label
        IleakageAEditField          matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        powerStatus = 0; % Tells if the setup is on or off
        handle_lockin = 0; % handle to lock-in amp
        handle_k6221 = 0; % handle to keithley 6221
        handle_k2400 = 0; % handle to keithley 2400
        handle_arduino = 0; % handle to arduino board
        acquisitionFlag = 0; % acquisition flag
        gatedMeasurement = 1; % True if gated measurement false if time measurement
        stopFlag = 0; % A stop flag
        dht = 0; % a handle to the DHT humidity sensor
        relativeHumidity = 0; % The current reading of the relative humidity in %
        temperature = 0; % The current reading of the temperature in °C
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            loadState(app);
        end

        % Value changed function: PowerSwitch
        function PowerSwitchValueChanged(app, event)
            value = app.PowerSwitch.Value;
            if app.powerStatus == 0
                [app.handle_k6221,app.handle_k2400,app.handle_lockin,app.handle_arduino] ...
                    = ConnectDevices(app);
                app.powerStatus = 1;
            else
                DisconnectDevices(app);
                app.powerStatus = 0;
            end
        end

        % Button pushed function: SelectDirectoryButton
        function SelectDirectoryButtonPushed(app, event)
            [folder_name] = uigetdir();
            app.DirectoryTextArea.Value = folder_name;
            figure(app.UIFigure);
        end

        % Value changed function: GateSwitch
        function GateSwitchValueChanged(app, event)
            value = app.GateSwitch.Value;
            if strcmp(value,'On')
                app.MeasurementTypeTabGroup.SelectedTab = app.GatedTab;
            else
                app.MeasurementTypeTabGroup.SelectedTab = app.UngatedTab;
            end
        end

        % Button pushed function: AbortButton
        function AbortButtonPushed(app, event)
            app.stopFlag = 1;
            app.StartButton.Text = 'Start';
            app.StartButton.BackgroundColor = [0.96 0.96 0.96];
            app.StatusLamp.Color = [0.8 0.8 0.8];
            app.IdleLampLabel.Text = 'Idle';
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            startMeasurement(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            saveState(app);
            delete(app);
        end

        % Value changed function: VgendVEditField
        function VgendVEditFieldValueChanged(app, event)
            value = app.VgendVEditField.Value;
            if (value == app.VgstartVEditField)
                warndlg("Start Vg should be different from end Vg");
                app.VgendVEditField.Value = 0;
            end
        end

        % Button pushed function: LoadFile
        function selectConfigFile(app, event)
            [file,path] = uigetfile('*.mat');
            loadFileConfiguration(app,file,path);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.DevicesPanel = uipanel(app.UIFigure);
            app.DevicesPanel.Title = 'Devices';
            app.Keithley2400LampLabel = uilabel(app.DevicesPanel);
            app.Keithley2400LampLabel.Text = 'Keithley 2400';
            app.Keithley2400Lamp = uilamp(app.DevicesPanel);
            app.Keithley6221LampLabel = uilabel(app.DevicesPanel);
            app.Keithley6221LampLabel.Text = 'Keithley 6221';
            app.Keithley6221Lamp = uilamp(app.DevicesPanel);
            app.LockinLampLabel = uilabel(app.DevicesPanel);
            app.LockinLampLabel.Text = 'Lock-in';
            app.LockinLamp = uilamp(app.DevicesPanel);
            app.PowerSwitchLabel = uilabel(app.DevicesPanel);
            app.PowerSwitchLabel.Text = 'Power';
            app.PowerSwitch = uiswitch(app.DevicesPanel, 'toggle');
            app.PowerSwitch.ValueChangedFcn = createCallbackFcn(app, @PowerSwitchValueChanged, true);
            app.k6621AddressEditField = uieditfield(app.DevicesPanel, 'numeric');
            app.k6621AddressEditField.Limits = [1 30];
            app.k6621AddressEditField.ValueDisplayFormat = '%.0f';
            app.k6621AddressEditField.Editable = 'off';
            app.k6621AddressEditField.Value = 26;
            app.AddressLabel = uilabel(app.DevicesPanel);
            app.AddressLabel.Text = 'Address';
            app.k2400AddressEditField = uieditfield(app.DevicesPanel, 'numeric');
            app.k2400AddressEditField.Limits = [1 30];
            app.k2400AddressEditField.Editable = 'off';
            app.k2400AddressEditField.Value = 25;
            app.LockinAddressEditField = uieditfield(app.DevicesPanel, 'numeric');
            app.LockinAddressEditField.Limits = [1 30];
            app.LockinAddressEditField.ValueDisplayFormat = '%.0f';
            app.LockinAddressEditField.Editable = 'off';
            app.LockinAddressEditField.Value = 8;
            app.ArduinoLampLabel = uilabel(app.DevicesPanel);
            app.ArduinoLampLabel.Text = 'Arduino';
            app.ArduinoLamp = uilamp(app.DevicesPanel);
            app.ArduinoAddressEditField = uieditfield(app.DevicesPanel, 'text');
            app.ArduinoAddressEditField.Editable = 'off';
            app.ArduinoAddressEditField.Value = 'COM4';
            app.AcquisitionPanel = uipanel(app.UIFigure);
            app.AcquisitionPanel.Title = 'Acquisition';
            app.SelectDirectoryButton = uibutton(app.AcquisitionPanel, 'push');
            app.SelectDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDirectoryButtonPushed, true);
            app.SelectDirectoryButton.Text = 'Select Directory';
            app.FileTagEditFieldLabel = uilabel(app.AcquisitionPanel);
            app.FileTagEditFieldLabel.Text = 'File Tag:';
            app.FileTagEditField = uieditfield(app.AcquisitionPanel, 'text');
            app.FileTagEditField.Value = 'Autosave';
            app.FrequencyHzEditFieldLabel = uilabel(app.AcquisitionPanel);
            app.FrequencyHzEditFieldLabel.Text = 'Frequency (Hz):';
            app.FrequencyHzEditField = uieditfield(app.AcquisitionPanel, 'numeric');
            app.FrequencyHzEditField.Limits = [1 1000000];
            app.FrequencyHzEditField.Value = 100;
            app.AmplitudeAEditFieldLabel = uilabel(app.AcquisitionPanel);
            app.AmplitudeAEditFieldLabel.Text = 'Amplitude (A):';
            app.AmplitudeAEditField = uieditfield(app.AcquisitionPanel, 'numeric');
            app.AmplitudeAEditField.Limits = [1e-09 0.1];
            app.AmplitudeAEditField.Value = 1e-05;
            app.StartButton = uibutton(app.AcquisitionPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.AbortButton = uibutton(app.AcquisitionPanel, 'push');
            app.AbortButton.ButtonPushedFcn = createCallbackFcn(app, @AbortButtonPushed, true);
            app.AbortButton.Text = 'Abort';
            app.DirectoryTextAreaLabel = uilabel(app.AcquisitionPanel);
            app.DirectoryTextAreaLabel.Text = 'Directory:';
            app.DirectoryTextArea = uitextarea(app.AcquisitionPanel);
            app.MeasurementTypeTabGroup = uitabgroup(app.AcquisitionPanel);
            app.GatedTab = uitab(app.MeasurementTypeTabGroup);
            app.GatedTab.Title = 'Gated';
            app.VgendVEditFieldLabel = uilabel(app.GatedTab);
            app.VgendVEditFieldLabel.Text = 'Vg end (V):';
            app.VgendVEditField = uieditfield(app.GatedTab, 'numeric');
            app.VgendVEditField.Limits = [-200 200];
            app.VgendVEditField.ValueChangedFcn = createCallbackFcn(app, @VgendVEditFieldValueChanged, true);
            app.VgstartVEditFieldLabel = uilabel(app.GatedTab);
            app.VgstartVEditFieldLabel.Text = 'Vg start (V):';
            app.VgstartVEditField = uieditfield(app.GatedTab, 'numeric');
            app.VgstartVEditField.Limits = [-200 200];
            app.VgstepVEditFieldLabel = uilabel(app.GatedTab);
            app.VgstepVEditFieldLabel.Text = 'Vg step (V):';
            app.VgstepVEditField = uieditfield(app.GatedTab, 'numeric');
            app.VgstepVEditField.Limits = [0.001 10];
            app.VgstepVEditField.Value = 0.1;
            app.ReverseSwitchLabel = uilabel(app.GatedTab);
            app.ReverseSwitchLabel.Text = 'Reverse';
            app.ReverseSwitch = uiswitch(app.GatedTab, 'slider');
            app.CyclesEditFieldLabel = uilabel(app.GatedTab);
            app.CyclesEditFieldLabel.Text = 'Cycles';
            app.CyclesEditField = uieditfield(app.GatedTab, 'numeric');
            app.CyclesEditField.Limits = [1 Inf];
            app.CyclesEditField.Value = 1;
            app.UngatedTab = uitab(app.MeasurementTypeTabGroup);
            app.UngatedTab.Title = 'Ungated';
            app.TimesEditFieldLabel = uilabel(app.UngatedTab);
            app.TimesEditFieldLabel.Text = 'Time (s)';
            app.TimesEditField = uieditfield(app.UngatedTab, 'numeric');
            app.TimesEditField.Limits = [10 Inf];
            app.TimesEditField.Value = 10;
            app.TimestepsEditFieldLabel = uilabel(app.UngatedTab);
            app.TimestepsEditFieldLabel.Text = 'Time step (s)';
            app.TimestepsEditField = uieditfield(app.UngatedTab, 'numeric');
            app.TimestepsEditField.Limits = [0.01 Inf];
            app.TimestepsEditField.Value = 2;
            app.AveragesEditFieldLabel = uilabel(app.AcquisitionPanel);
            app.AveragesEditFieldLabel.Text = 'Averages';
            app.AveragesEditField = uieditfield(app.AcquisitionPanel, 'numeric');
            app.AveragesEditField.Limits = [1 100];
            app.AveragesEditField.ValueDisplayFormat = '%.0f';
            app.AveragesEditField.Value = 1;
            app.GateSwitchLabel = uilabel(app.AcquisitionPanel);
            app.GateSwitchLabel.Text = 'Gate';
            app.GateSwitch = uiswitch(app.AcquisitionPanel, 'slider');
            app.GateSwitch.ValueChangedFcn = createCallbackFcn(app, @GateSwitchValueChanged, true);
            app.GateSwitch.Value = 'On';
            app.LoadFile = uibutton(app.AcquisitionPanel, 'push');
            app.LoadFile.ButtonPushedFcn = createCallbackFcn(app, @selectConfigFile, true);
            app.LoadFile.Text = 'Load File';
            app.GraphsPanel = uipanel(app.UIFigure);
            app.GraphsPanel.Title = 'Graphs';
            app.RVAxes = uiaxes(app.GraphsPanel);
            title(app.RVAxes, 'Lock-in Resistivity')
            xlabel(app.RVAxes, 'V_g (V)')
            ylabel(app.RVAxes, 'Resistivity (Ohm)')
            app.IdleLampLabel = uilabel(app.GraphsPanel);
            app.IdleLampLabel.Text = 'Idle';
            app.StatusLamp = uilamp(app.GraphsPanel);
            app.DatapointLabel = uilabel(app.GraphsPanel);
            app.DatapointLabel.Text = 'Data point:';
            app.DatapointEditField = uieditfield(app.GraphsPanel, 'text');
            app.DatapointEditField.Value = '0/0';
            app.VgVEditFieldLabel = uilabel(app.GraphsPanel);
            app.VgVEditFieldLabel.Text = 'Vg (V):';
            app.VgVEditField = uieditfield(app.GraphsPanel, 'numeric');
            app.VgVEditField.Editable = 'off';
            app.ROhmsEditFieldLabel = uilabel(app.GraphsPanel);
            app.ROhmsEditFieldLabel.Text = 'R (Ohms):';
            app.ROhmsEditField = uieditfield(app.GraphsPanel, 'numeric');
            app.ROhmsEditField.Editable = 'off';
            app.SweeprateVsEditFieldLabel = uilabel(app.GraphsPanel);
            app.SweeprateVsEditFieldLabel.Text = 'Sweep rate: (V/s):';
            app.SweeprateVsEditField = uieditfield(app.GraphsPanel, 'numeric');
            app.SweeprateVsEditField.Editable = 'off';
            app.ElapsedtimesEditFieldLabel = uilabel(app.GraphsPanel);
            app.ElapsedtimesEditFieldLabel.Text = 'Elapsed time (s):';
            app.ElapsedtimesEditField = uieditfield(app.GraphsPanel, 'numeric');
            app.ElapsedtimesEditField.Editable = 'off';
            app.IleakageAEditFieldLabel = uilabel(app.GraphsPanel);
            app.IleakageAEditFieldLabel.Text = 'I leakage (A)';
            app.IleakageAEditField = uieditfield(app.GraphsPanel, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = Resistivity_GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end